classdef app_designer_DOSE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        GridLayout4                   matlab.ui.container.GridLayout
        ViewresultsinREGGUIButton     matlab.ui.control.Button
        GetTimeTracesButton           matlab.ui.control.Button
        ComputeInfluenceMatrixButton  matlab.ui.control.Button
        GridLayout8                   matlab.ui.container.GridLayout
        ModeDropDown                  matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        UseirradiationlogCheckBox     matlab.ui.control.CheckBox
        UsedeliveredspotpositionslogCheckBox  matlab.ui.control.CheckBox
        GridLayout7                   matlab.ui.container.GridLayout
        OpenOutputFolderButton        matlab.ui.control.Button
        EditField_14                  matlab.ui.control.EditField
        CloseButton                   matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        DosegridEditField             matlab.ui.control.EditField
        DoseThresholdEditField        matlab.ui.control.NumericEditField
        DosegridmmLabel               matlab.ui.control.Label
        NbofprotonsLabel              matlab.ui.control.Label
        DoseThresholdGyLabel          matlab.ui.control.Label
        NbofprotonsEditField          matlab.ui.control.NumericEditField
        GridLayout5                   matlab.ui.container.GridLayout
        TargetDropDown                matlab.ui.control.DropDown
        BodyDropDown                  matlab.ui.control.DropDown
        TargetLabel                   matlab.ui.control.Label
        BodyLabel                     matlab.ui.control.Label
        InputFilesPanel               matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        RTstructEditField             matlab.ui.control.EditField
        OutputfolderSelectButton      matlab.ui.control.Button
        MCsquareSelectButton          matlab.ui.control.Button
        ScannerSelectButton           matlab.ui.control.Button
        BDLSelectButton               matlab.ui.control.Button
        LogfileSelectButton           matlab.ui.control.Button
        CTSelectButton                matlab.ui.control.Button
        RTstructSelectButton          matlab.ui.control.Button
        PlanSelectButton              matlab.ui.control.Button
        OutputfolderEditField         matlab.ui.control.EditField
        MCsquareEditField             matlab.ui.control.EditField
        ScannerEditField              matlab.ui.control.EditField
        BDLEditField                  matlab.ui.control.EditField
        LogfileEditField              matlab.ui.control.EditField
        CTEditField                   matlab.ui.control.EditField
        PlanEditField                 matlab.ui.control.EditField
        OutputfolderLabel             matlab.ui.control.Label
        MCsquareLabel                 matlab.ui.control.Label
        ScannerLabel                  matlab.ui.control.Label
        BDLLabel                      matlab.ui.control.Label
        LogfileLabel                  matlab.ui.control.Label
        CTLabel                       matlab.ui.control.Label
        RTstructLabel                 matlab.ui.control.Label
        PlanLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        ShootThroughSettings
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UITable.Data = {
            '', '', '', '';
            '', '', '', '';
            '', '', '', ''
            };

            %memoire de l'utilisation precedente
            if ispref('DoseRateQA','planFileName')
                app.PlanEditField.Value = getpref('DoseRateQA','planFileName');
            end
            if ispref('DoseRateQA','rtstructFileName')
                app.RTstructEditField.Value = getpref('DoseRateQA','rtstructFileName');
                if exist(app.RTstructEditField.Value,'file')
                    RTstrucOUT = read_dicomrtstruct(app.RTstructEditField.Value);
                    StructList = convertCharsToStrings({RTstrucOUT.Struct.Name});
                    app.BodyDropDown.Items = cellstr(StructList);
                    app.TargetDropDown.Items = cellstr(StructList);

                    [~,index] = findClosestString(StructList,'BODY');
                    app.BodyDropDown.Value = char(StructList(index(1)));

                    [~,index] = findClosestString(StructList,'CTV');
                    app.TargetDropDown.Value = char(StructList(index(1)));


                    % Restaurer la derniere selection utilisateur

                    if ispref('DoseRateQA','BodyROI')
                        roi = getpref('DoseRateQA','BodyROI');
                        if any(strcmp(app.BodyDropDown.Items,roi))
                            app.BodyDropDown.Value = roi;
                        end
                    end
                    if ispref('DoseRateQA','TargetROI')
                        roi = getpref('DoseRateQA','TargetROI');
                        if any(strcmp(app.TargetDropDown.Items,roi))
                            app.TargetDropDown.Value = roi;
                        end
                    end

                end
            end
            if ispref('DoseRateQA','CTname')
                app.CTEditField.Value = getpref('DoseRateQA','CTname');
            end
            if ispref('DoseRateQA','RecordName')
                app.LogfileEditField.Value = getpref('DoseRateQA','RecordName');        
            end
            if ispref('DoseRateQA','BDL')
                app.BDLEditField.Value = getpref('DoseRateQA','BDL');
            end
            if ispref('DoseRateQA','ScannerDirectory')
                app.ScannerEditField.Value = getpref('DoseRateQA','ScannerDirectory');
            end
            if ispref('DoseRateQA','MCsqExecPath')
                app.MCsquareEditField.Value = getpref('DoseRateQA','MCsqExecPath');        
            end
            if ispref('DoseRateQA','output_path')
                app.OutputfolderEditField.Value = getpref('DoseRateQA','output_path');
            end

            if ispref('DoseRateQA','editDose')
                app.DoseThresholdEditField.Value = str2double(getpref('DoseRateQA','editDose'));
            end
            if ispref('DoseRateQA','editNbProtons')
                app.NbofprotonsEditField.Value = str2double(getpref('DoseRateQA','editNbProtons'));
            end

            if ispref('DoseRateQA','editDoseGrid')
                app.DosegridEditField.Value = getpref('DoseRateQA','editDoseGrid');
            end
            if ispref('DoseRateQA','uitablePointList')
                app.UITable.Data = getpref('DoseRateQA','uitablePointList');

end
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: PlanSelectButton
        function PlanSelectButtonPushed(app, event)
            [file, path] = uigetfile('*.dcm', 'Select treatment plan');
            if file ~= 0
                app.PlanEditField.Value = fullfile(path, file);
                setpref('DoseRateQA','planFileName', fullfile(path,file));

            end
        end

        % Button pushed function: RTstructSelectButton
        function RTstructSelectButtonPushed(app, event)
            [file, path] = uigetfile('*.dcm', 'Select RT structure');
            if file ~= 0
                filename = fullfile(path,file);
                %memorisation du chemin pour les prochaines utilisations
                setpref('DoseRateQA','rtstructFileName', fullfile(path,file));
                % Afficher le chemin
                app.RTstructEditField.Value = filename;
                % Lire le RTStruct
                RTstrucOUT = read_dicomrtstruct(filename);
                % Récupérer les noms des ROI
                StructList = convertCharsToStrings({RTstrucOUT.Struct.Name});
                % Remplir les dropdowns
                app.BodyDropDown.Items = cellstr(StructList);
                app.TargetDropDown.Items = cellstr(StructList);
                [~, index] = findClosestString(StructList,'BODY');
                app.BodyDropDown.Value = char(StructList(index(1)));

                [~, index] = findClosestString(StructList,'CTV');
                app.TargetDropDown.Value = char(StructList(index(1)));

                if ispref('DoseRateQA','BodyROI')
                    roi = getpref('DoseRateQA','BodyROI');
                    if any(strcmp(app.BodyDropDown.Items,roi))
                        app.BodyDropDown.Value = roi;
                    end

                end

                if ispref('DoseRateQA','TargetROI')
                    roi = getpref('DoseRateQA','TargetROI');
                    if any(strcmp(app.TargetDropDown.Items,roi))
                        app.TargetDropDown.Value = roi;
                    end
                end
            end
        end

        % Button pushed function: ComputeInfluenceMatrixButton
        function ComputeInfluenceMatrixButtonPushed(app, event)
            disp(app.ShootThroughSettings)
            % Retrieve the ROI selected in the dropdown menus
            bodyROI = app.BodyDropDown.Value;
            targetROI = app.TargetDropDown.Value;
            
            % Check whether output folder is empty

            listing = dir(app.OutputfolderEditField.Value);
            listing = listing(~ismember({listing.name},{'.','..'}));

            if ~isempty(listing)

                h = msgbox(['Output folder not empty. Existing data will be reused.' newline ...
                    'You should provide an empty folder to refresh all computations.'], ...
                    'Warning','warn');
                waitfor(h);

            end

            % Save the last values used so they can be restored next time 
            setpref('DoseRateQA','BodyROI',bodyROI);
            setpref('DoseRateQA','TargetROI',targetROI)
            doseThreshold = app.DoseThresholdEditField.Value;
            nbProtons = app.NbofprotonsEditField.Value;
            setpref('DoseRateQA','editDose',num2str(doseThreshold));
            setpref('DoseRateQA','editNbProtons',num2str(nbProtons));
            setpref('DoseRateQA','editDoseGrid',app.DosegridEditField.Value);

            %convert the input 3,3,3 into [3 3 3] 
            doseGridText = strrep(app.DosegridEditField.Value, ',', ' ');
            doseGrid = str2num(doseGridText);

            %verify that the user gave exactly 3 values
            if isempty(doseGrid) || numel(doseGrid) ~= 3
                uialert(app.UIFigure,...
                'Dose grid must contain 3 values (example: 3 3 3)',...
                'Input Error');

                return
            end

            % List of missing or invalid inputs detected during validation
            missingInputs = {};

            if ~exist(app.PlanEditField.Value,'file')
                missingInputs{end+1} = 'Plan';
            end

            if ~exist(app.RTstructEditField.Value,'file')
                missingInputs{end+1} = 'RT Struct';
            end

            if ~exist(app.CTEditField.Value,'file')
                missingInputs{end+1} = 'CT';
            end

            if app.UseirradiationlogCheckBox.Value    
                if ~exist(app.LogfileEditField.Value,'file')
                    missingInputs{end+1} = 'Log file';
                end
            end

            if ~exist(app.BDLEditField.Value,'file')
                missingInputs{end+1} = 'BDL';
            end

            if ~exist(app.ScannerEditField.Value,'dir')
                missingInputs{end+1} = 'Scanner';
            end

            if ~exist(app.MCsquareEditField.Value,'dir')
                missingInputs{end+1} = 'MCsquare';
            end

            if ~exist(app.OutputfolderEditField.Value,'dir')
                missingInputs{end+1} = 'Output folder';
            end
            
            %if missingInputs is not empty, a warning popup appears
            if ~isempty(missingInputs)
                uialert(app.UIFigure,...
                    sprintf(['The following inputs are missing or invalid:\n\n%s'],...
                    strjoin(missingInputs,newline)),...
                    'Input Error');

                return

            end
            
            % Configuration of 'config' to run 'runDRqa'
            config.Mode = app.ModeDropDown.Value;
            if strcmp(app.ModeDropDown.Value,'Shoot Through')
                config.ShootThrough = app.ShootThroughSettings;
            end
            config.files.planFileName = app.PlanEditField.Value;
            config.files.rtstructFileName = app.RTstructEditField.Value;
            config.files.CTname = app.CTEditField.Value;
            config.files.RecordName = app.LogfileEditField.Value;
            config.files.output_path = app.OutputfolderEditField.Value;

            config.Analysis.DoseMin = doseThreshold;
                
            config.RTstruct.ExternalROI = remove_bad_chars(bodyROI);
            config.Analysis.Target = remove_bad_chars(targetROI);


            config.BeamProp.protonsHighResDose = nbProtons;
            config.BeamProp.CEFDoseGrid = doseGrid;
            config.BeamProp.BDL = app.BDLEditField.Value;
            config.BeamProp.ScannerDirectory = app.ScannerEditField.Value;
            config.BeamProp.MCsqExecPath = app.MCsquareEditField.Value;
            % Use delivered spot positions from logs if enabled
            config.BeamProp.UseLogSpotPositions = app.UsedeliveredspotpositionslogCheckBox.Value;
            % Enable or disable irradiation log usage entirely
            config.BeamProp.UseIrradiationLog = app.UseirradiationlogCheckBox.Value;


            config.BeamProp.DICOMdict = fullfile(config.BeamProp.MCsqExecPath,'dicom-dict.txt');
            config.RTstruct.DRPercentile = 0.95;
            config.files.AggregatePaintings = 0;

            % Delete all open figures except this app
            h = findall(groot,'Type','figure');
            for k = 1:numel(h)
                if h(k) ~= app.UIFigure
                    try
                        close(h(k));
                    catch
                    end
                end

            end

            % Update the status display to "Computing..."
            app.EditField_14.Value = 'Computing...';
            drawnow;

            % Buttons are turned off during computation
            app.ComputeInfluenceMatrixButton.Enable = 'off';
            app.GetTimeTracesButton.Enable = 'off';
            app.ViewresultsinREGGUIButton.Enable = 'off';
            app.OpenOutputFolderButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            
            % Tries to run 'runDRqa(config)' and if it fails, turns the
            % buttons on and displays the Matlab error
            try
                runDRqa(config);

            catch ME
                app.ComputeInfluenceMatrixButton.Enable = 'on';
                app.GetTimeTracesButton.Enable = 'on';
                app.ViewresultsinREGGUIButton.Enable = 'on';
                app.OpenOutputFolderButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                rethrow(ME);
            end
            
            %turns the buttons on when the computation is finished
            app.ComputeInfluenceMatrixButton.Enable = 'on';
            app.GetTimeTracesButton.Enable = 'on';
            app.ViewresultsinREGGUIButton.Enable = 'on';
            app.OpenOutputFolderButton.Enable = 'on';
            app.CloseButton.Enable = 'on';
            app.EditField_14.Value = 'Computation finished';
            drawnow;
        end

        % Button pushed function: CTSelectButton
        function CTSelectButtonPushed(app, event)
            [file, path] = uigetfile('*.dcm', 'Select CT scan');
            if file ~= 0
                app.CTEditField.Value = fullfile(path,file);
                setpref('DoseRateQA','CTname', fullfile(path,file));
            end
        end

        % Button pushed function: LogfileSelectButton
        function LogfileSelectButtonPushed(app, event)
            [file, path] = uigetfile('*.zip', 'Select log file');
            if file ~= 0
                app.LogfileEditField.Value = fullfile(path,file);
                setpref('DoseRateQA','RecordName', fullfile(path,file));
            end
        end

        % Button pushed function: BDLSelectButton
        function BDLSelectButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Select beam data library');
            if file ~= 0
                app.BDLEditField.Value = fullfile(path,file);
                setpref('DoseRateQA','BDL', fullfile(path,file));
            end 
        end

        % Button pushed function: ScannerSelectButton
        function ScannerSelectButtonPushed(app, event)
            path = uigetdir(pwd,'Select scanner folder');
            if path ~= 0
                app.ScannerEditField.Value = path;
                setpref('DoseRateQA','ScannerDirectory', path);
            end
        end

        % Button pushed function: MCsquareSelectButton
        function MCsquareSelectButtonPushed(app, event)
            path = uigetdir(pwd,'Select MCsquare folder');
            if path ~= 0
                app.MCsquareEditField.Value = path;
                setpref('DoseRateQA','MCsqExecPath', path);
            end
        end

        % Button pushed function: OutputfolderSelectButton
        function OutputfolderSelectButtonPushed(app, event)
            path = uigetdir(pwd,'Select output folder');
            if path ~= 0
                app.OutputfolderEditField.Value = path;
                setpref('DoseRateQA','output_path', path);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'Do you really want to close the application?','Close Application','Options',{'Yes','No'},'DefaultOption',2);
            if strcmp(selection,'Yes')
                close all force
                delete(app);
            end
        end

        % Button pushed function: GetTimeTracesButton
        function GetTimeTracesButtonPushed(app, event)
            data = app.UITable.Data;
            setpref('DoseRateQA','uitablePointList',data);
            % Remove empty lines
            flag = cellfun(@isempty,data(:,2:4));
            flag = sum(flag,2);
            i = find(~flag);
            GoodLines = unique(i);
            Pg = cellfun(@str2num,data(GoodLines,2:4));
            config.files.output_path = app.OutputfolderEditField.Value;
            config.files.planFileName = app.PlanEditField.Value;
            config.RTstruct.ExternalROI = ...
                remove_bad_chars(app.BodyDropDown.Value);
            titleSTR = data(:,1);

            app.EditField_14.Value = 'Computing...';
            drawnow;
            runGetDoseTiming(config,Pg,titleSTR);
    
            app.EditField_14.Value = 'Computation finished';
            drawnow;

        end

        % Button pushed function: ViewresultsinREGGUIButton
        function ViewresultsinREGGUIButtonPushed(app, event)
            
            [CTfolder,CTname,CText] = ...
                fileparts(getpref('DoseRateQA','CTname',pwd));
        
            CTname = [CTname, CText];
        
            inst1 = "handles=Import_image('" + string(CTfolder) + "','";
            inst1 = inst1.append(string(CTname) + "',");
            inst1 = inst1.append("1,'CT',handles);");
        
            inst2 = "handles=Import_contour('" + ...
                string(app.RTstructEditField.Value) + ...
                "','all','CT',1,handles);";

            outputFolder = fullfile( ...
                app.OutputfolderEditField.Value, ...
                'Outputs', ...
                'Outputs_beam1');

            files = dir(outputFolder);

            inst3 = "";
            inst4 = "";
            inst5 = "";
            
            for k = 1:length(files)
            
                if contains(files(k).name,'Dose')
            
                    fileDose = files(k).name;
            
                    inst3 = "handles=Import_image('" + ...
                        string(outputFolder) + "','";
            
                    inst3 = inst3.append(string(fileDose) + "',");
                    inst3 = inst3.append("1,'Dose',handles);");
            
                end
            
                if contains(files(k).name,'DADR')
            
                    fileDADR = files(k).name;
            
                    inst4 = "handles=Import_image('" + ...
                        string(outputFolder) + "','";
            
                    inst4 = inst4.append(string(fileDADR) + "',");
                    inst4 = inst4.append("1,'DADR',handles);");
            
                end
            
                if contains(files(k).name,'PDR')
            
                    filePDR = files(k).name;
            
                    inst5 = "handles=Import_image('" + ...
                        string(outputFolder) + "','";
            
                    inst5 = inst5.append(string(filePDR) + "',");
                    inst5 = inst5.append("1,'PDR',handles);");
            
                end
            
            end

            inst10 = "handles.image1.Value=2;";
            inst11 = "handles.image2.Value=2;";
            inst12 = "handles.image3.Value=2;";
            inst13 = "handles.image4.Value=2;";
            
            inst98 = "Update_regguiC_GUI(handles);";
            inst99 = "handles=Apply_view_point(handles);";
            inst100 = "Update_regguiC_all_plots(handles);";
            
            instructions = {inst1, inst2, inst3, inst4, inst5, inst10, inst11, inst12, inst13, inst98, inst99, inst100};
            

            reggui('GUI',1,'dataPath',app.OutputfolderEditField.Value,'workflow',instructions);

       
        end

        % Callback function
        function OpenOutputFolder(app, event)
            
        end

        % Button pushed function: OpenOutputFolderButton
        function OpenOutputFolderPushedFcn(app, event)
            folder = app.OutputfolderEditField.Value;
            if exist(folder,'dir')
                winopen(folder);
            else
                uialert(app.UIFigure,...
                'The output folder does not exist.',...
                'Folder Error');
            end

        end

        % Value changed function: UseirradiationlogCheckBox
        function UseirradiationlogValueChangedFcn(app, event)
            value = app.UseirradiationlogCheckBox.Value;
            if value
                app.UsedeliveredspotpositionslogCheckBox.Enable = 'on';
                app.LogfileSelectButton.Enable = 'on';
                app.LogfileEditField.Enable = 'on';
            else        
                app.UsedeliveredspotpositionslogCheckBox.Value = false;
                app.UsedeliveredspotpositionslogCheckBox.Enable = 'off';
                app.LogfileSelectButton.Enable = 'off';
                app.LogfileEditField.Enable = 'off';
            end
        end

        % Value changed function: ModeDropDown
        function ModebuttonValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if strcmp(value,'Shoot Through')
                stApp = app_designer_ShootThrough;
                stApp.MainApp = app;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputFilesPanel = uipanel(app.GridLayout);
            app.InputFilesPanel.Title = 'Input Files';
            app.GridLayout2 = uigridlayout(app.InputFilesPanel);
            app.PlanLabel = uilabel(app.GridLayout2);
            app.PlanLabel.Tooltip = {'RT Ion Plan (DICOM). It has to be a ConformalFLASH plan, containing the descriptions of the CEM, the range shifting plates combination, and aperture.'};
            app.PlanLabel.Text = 'Plan';
            app.RTstructLabel = uilabel(app.GridLayout2);
            app.RTstructLabel.Tooltip = {'RT Struct (DICOM). Contouring or PTV, OAR,...'};
            app.RTstructLabel.Text = 'RT struct';
            app.CTLabel = uilabel(app.GridLayout2);
            app.CTLabel.Tooltip = {'CT (DICOM series)'};
            app.CTLabel.Text = 'CT';
            app.LogfileLabel = uilabel(app.GridLayout2);
            app.LogfileLabel.Tooltip = {'Log file (ZIP)'};
            app.LogfileLabel.Text = 'Log file';
            app.BDLLabel = uilabel(app.GridLayout2);
            app.BDLLabel.Tooltip = {'Beam Data Library (MCsquare format, see http://www.openmcsquare.org/documentation_commissioning.html#BDL_format)'};
            app.BDLLabel.Text = 'BDL';
            app.ScannerLabel = uilabel(app.GridLayout2);
            app.ScannerLabel.Tooltip = {'CT Scanner calibration (MCsquare format, see http://www.openmcsquare.org/documentation_CT_calibration.html)'};
            app.ScannerLabel.Text = 'Scanner';
            app.MCsquareLabel = uilabel(app.GridLayout2);
            app.MCsquareLabel.Tooltip = {'The folder with the MCsquare executables (by default, downloaded under openMCsquare)'};
            app.MCsquareLabel.Text = 'MCsquare';
            app.OutputfolderLabel = uilabel(app.GridLayout2);
            app.OutputfolderLabel.Tooltip = {'Output folder to store the output files. The output folder must be EMPTY'};
            app.OutputfolderLabel.Text = 'Output folder';
            app.PlanEditField = uieditfield(app.GridLayout2, 'text');
            app.PlanEditField.Editable = 'off';
            app.CTEditField = uieditfield(app.GridLayout2, 'text');
            app.CTEditField.Editable = 'off';
            app.LogfileEditField = uieditfield(app.GridLayout2, 'text');
            app.LogfileEditField.Editable = 'off';
            app.BDLEditField = uieditfield(app.GridLayout2, 'text');
            app.BDLEditField.Editable = 'off';
            app.ScannerEditField = uieditfield(app.GridLayout2, 'text');
            app.ScannerEditField.Editable = 'off';
            app.MCsquareEditField = uieditfield(app.GridLayout2, 'text');
            app.MCsquareEditField.Editable = 'off';
            app.OutputfolderEditField = uieditfield(app.GridLayout2, 'text');
            app.OutputfolderEditField.Editable = 'off';
            app.PlanSelectButton = uibutton(app.GridLayout2, 'push');
            app.PlanSelectButton.ButtonPushedFcn = createCallbackFcn(app, @PlanSelectButtonPushed, true);
            app.PlanSelectButton.Text = 'Select';
            app.RTstructSelectButton = uibutton(app.GridLayout2, 'push');
            app.RTstructSelectButton.ButtonPushedFcn = createCallbackFcn(app, @RTstructSelectButtonPushed, true);
            app.RTstructSelectButton.Text = 'Select';
            app.CTSelectButton = uibutton(app.GridLayout2, 'push');
            app.CTSelectButton.ButtonPushedFcn = createCallbackFcn(app, @CTSelectButtonPushed, true);
            app.CTSelectButton.Text = 'Select';
            app.LogfileSelectButton = uibutton(app.GridLayout2, 'push');
            app.LogfileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @LogfileSelectButtonPushed, true);
            app.LogfileSelectButton.Text = 'Select';
            app.BDLSelectButton = uibutton(app.GridLayout2, 'push');
            app.BDLSelectButton.ButtonPushedFcn = createCallbackFcn(app, @BDLSelectButtonPushed, true);
            app.BDLSelectButton.Text = 'Select';
            app.ScannerSelectButton = uibutton(app.GridLayout2, 'push');
            app.ScannerSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ScannerSelectButtonPushed, true);
            app.ScannerSelectButton.Text = 'Select';
            app.MCsquareSelectButton = uibutton(app.GridLayout2, 'push');
            app.MCsquareSelectButton.ButtonPushedFcn = createCallbackFcn(app, @MCsquareSelectButtonPushed, true);
            app.MCsquareSelectButton.Text = 'Select';
            app.OutputfolderSelectButton = uibutton(app.GridLayout2, 'push');
            app.OutputfolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @OutputfolderSelectButtonPushed, true);
            app.OutputfolderSelectButton.Text = 'Select';
            app.RTstructEditField = uieditfield(app.GridLayout2, 'text');
            app.RTstructEditField.Editable = 'off';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.BodyLabel = uilabel(app.GridLayout5);
            app.BodyLabel.Text = 'Body';
            app.TargetLabel = uilabel(app.GridLayout5);
            app.TargetLabel.Text = 'Target';
            app.BodyDropDown = uidropdown(app.GridLayout5);
            app.TargetDropDown = uidropdown(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.NbofprotonsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.DoseThresholdGyLabel = uilabel(app.GridLayout6);
            app.DoseThresholdGyLabel.Tooltip = {'The Dose threshold: only applied to the average DADR in the PTV computation'};
            app.DoseThresholdGyLabel.Text = 'Dose Threshold (Gy)';
            app.NbofprotonsLabel = uilabel(app.GridLayout6);
            app.NbofprotonsLabel.Tooltip = {'Nb of protons: per beamlet, for the Monte Carlo simulation'};
            app.NbofprotonsLabel.Text = 'Nb of protons';
            app.DosegridmmLabel = uilabel(app.GridLayout6);
            app.DosegridmmLabel.Tooltip = {'Dose grid: only for the final scoring. The geometry for the Monte Carlo uses the full resolution of the CT and a higher resolution for the CEM representation (see https://github.com/openFLASH/conformalFLASH/wiki/algorithms)'};
            app.DosegridmmLabel.Text = 'Dose grid (mm)';
            app.DoseThresholdEditField = uieditfield(app.GridLayout6, 'numeric');
            app.DosegridEditField = uieditfield(app.GridLayout6, 'text');
            app.DosegridEditField.Value = '0';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Not a clinical product. Not a commercial product. For research purpose only.';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout7, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Close all open windows'};
            app.CloseButton.Text = 'Close';
            app.EditField_14 = uieditfield(app.GridLayout7, 'text');
            app.EditField_14.Editable = 'off';
            app.EditField_14.Tooltip = {'Current status'};
            app.EditField_14.Value = 'Select files';
            app.OpenOutputFolderButton = uibutton(app.GridLayout7, 'push');
            app.OpenOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenOutputFolderPushedFcn, true);
            app.OpenOutputFolderButton.Text = 'Open Output Folder';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.UsedeliveredspotpositionslogCheckBox = uicheckbox(app.GridLayout8);
            app.UsedeliveredspotpositionslogCheckBox.Text = 'Use delivered spot positions (log)';
            app.UseirradiationlogCheckBox = uicheckbox(app.GridLayout8);
            app.UseirradiationlogCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseirradiationlogValueChangedFcn, true);
            app.UseirradiationlogCheckBox.Text = 'Use irradiation log';
            app.UseirradiationlogCheckBox.Value = true;
            app.ModeDropDownLabel = uilabel(app.GridLayout8);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout8);
            app.ModeDropDown.Items = {'Conformal Flash', 'Shoot Through'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModebuttonValueChanged, true);
            app.ModeDropDown.Value = 'Conformal Flash';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ComputeInfluenceMatrixButton = uibutton(app.GridLayout4, 'push');
            app.ComputeInfluenceMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeInfluenceMatrixButtonPushed, true);
            app.ComputeInfluenceMatrixButton.Text = 'Compute Influence Matrix';
            app.GetTimeTracesButton = uibutton(app.GridLayout4, 'push');
            app.GetTimeTracesButton.ButtonPushedFcn = createCallbackFcn(app, @GetTimeTracesButtonPushed, true);
            app.GetTimeTracesButton.Text = 'Get Time Traces';
            app.ViewresultsinREGGUIButton = uibutton(app.GridLayout4, 'push');
            app.ViewresultsinREGGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ViewresultsinREGGUIButtonPushed, true);
            app.ViewresultsinREGGUIButton.Text = 'View results in REGGUI';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Point Name'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_designer_DOSE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end