classdef app_designer_ShootThrough < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        ApertureThicknessbydefault60mmEditField  matlab.ui.control.NumericEditField
        ApertureThicknessbydefault60mmEditFieldLabel  matlab.ui.control.Label
        SnoutPositionEditField          matlab.ui.control.NumericEditField
        SnoutPositionEditFieldLabel     matlab.ui.control.Label
        PLDGridLayout                   matlab.ui.container.GridLayout
        PLDButton                       matlab.ui.control.Button
        PLDEditField                    matlab.ui.control.EditField
        PLDFileLabel                    matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        ApertureGridLayout              matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        WidthEditField                  matlab.ui.control.NumericEditField
        RadiusmmLabel                   matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        RadiusEditField                 matlab.ui.control.NumericEditField
        HeightmmLabel                   matlab.ui.control.Label
        WidthmmLabel                    matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        ApertureShapeDropDown           matlab.ui.control.DropDown
        ApertureShapeDropDownLabel      matlab.ui.control.Label
        ApertureLabel                   matlab.ui.control.Label
        RangeShifterGridLayout          matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        AccessoryCodeDropDown           matlab.ui.control.DropDown
        AccessoryCodeDropDownLabel      matlab.ui.control.Label
        SnoutTypeEditField              matlab.ui.control.EditField
        SnoutTypeEditFieldLabel         matlab.ui.control.Label
        RangeShifterLabel               matlab.ui.control.Label
        ShootThroughConfigurationLabel  matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.SnoutTypeEditField.Value = 'FLASH_Snout_S';
            snout = getParamSnout(app.SnoutTypeEditField.Value);
            codes = keys(snout.RangeShifterSlabs);
            app.AccessoryCodeDropDown.Items = codes;
            app.AccessoryCodeDropDown.Value = codes{1};
        end

        % Value changed function: ApertureShapeDropDown
        function ApertureShapeDropDownValueChanged(app, event)
            value = app.ApertureShapeDropDown.Value;
            switch value

            case 'Circle'

                app.RadiusmmLabel.Visible = 'on';
                app.RadiusEditField.Visible = 'on';
    
                app.WidthmmLabel.Visible = 'off';
                app.WidthEditField.Visible = 'off';
    
                app.HeightmmLabel.Visible = 'off';
                app.HeightEditField.Visible = 'off';

            case 'Rectangle'

                app.RadiusmmLabel.Visible = 'off';
                app.RadiusEditField.Visible = 'off';
    
                app.WidthmmLabel.Visible = 'on';
                app.WidthEditField.Visible = 'on';
    
                app.HeightmmLabel.Visible = 'on';
                app.HeightEditField.Visible = 'on';

            end
        end

        % Button pushed function: PLDButton
        function SelectButtonPushedFcn(app, event)
            [file,path] = uigetfile( ...
                {'*.pld','PLD Files (*.pld)'}, ...
                'Select PLD file');

            if isequal(file,0)
                return;
            end

            app.PLDEditField.Value = fullfile(path,file);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainApp.ShootThroughSettings = struct();
            app.MainApp.ShootThroughSettings.PLD = app.PLDEditField.Value;
            app.MainApp.ShootThroughSettings.SnoutType = app.SnoutTypeEditField.Value;
            app.MainApp.ShootThroughSettings.AccessoryCode = app.AccessoryCodeDropDown.Value;
            app.MainApp.ShootThroughSettings.Shape = app.ApertureShapeDropDown.Value;
            switch app.ApertureShapeDropDown.Value
                case 'Circle'
                    app.MainApp.ShootThroughSettings.Radius=app.RadiusEditField.Value;
                case 'Rectangle'
                    app.MainApp.ShootThroughSettings.Height=app.HeightEditField.Value;
                    app.MainApp.ShootThroughSettings.Width=app.WidthEditField.Value;
            end
            app.MainApp.ShootThroughSettings.SnoutPosition = app.SnoutPositionEditField.Value;
            app.MainApp.ShootThroughSettings.ApertureThickness = app.ApertureThicknessbydefault60mmEditField.Value;
            disp('OK pressed')
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushedFcn(app, event)
            app.MainApp.ModeDropDown.Value = 'Conformal Flash';    
            delete(app);
        end

        % Value changed function: SnoutTypeEditField
        function SnoutTypeEditFieldValueChanged(app, event)
            snout = getParamSnout(app.SnoutTypeEditField.Value);
            codes = keys(snout.RangeShifterSlabs);
            app.AccessoryCodeDropDown.Items = codes;
            app.AccessoryCodeDropDown.Value = codes{1};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OKButton = uibutton(app.GridLayout2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushedFcn, true);
            app.CancelButton.Text = 'Cancel';
            app.ShootThroughConfigurationLabel = uilabel(app.GridLayout);
            app.ShootThroughConfigurationLabel.Text = 'Shoot Through Configuration';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RangeShifterGridLayout = uigridlayout(app.GridLayout3);
            app.RangeShifterLabel = uilabel(app.RangeShifterGridLayout);
            app.RangeShifterLabel.Text = 'Range Shifter';
            app.GridLayout6 = uigridlayout(app.RangeShifterGridLayout);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.SnoutTypeEditFieldLabel = uilabel(app.GridLayout8);
            app.SnoutTypeEditFieldLabel.Text = 'Snout Type';
            app.SnoutTypeEditField = uieditfield(app.GridLayout8, 'text');
            app.SnoutTypeEditField.ValueChangedFcn = createCallbackFcn(app, @SnoutTypeEditFieldValueChanged, true);
            app.SnoutTypeEditField.Value = 'FLASH_Snout_S';
            app.AccessoryCodeDropDownLabel = uilabel(app.GridLayout8);
            app.AccessoryCodeDropDownLabel.Text = 'Accessory Code';
            app.AccessoryCodeDropDown = uidropdown(app.GridLayout8);
            app.ApertureGridLayout = uigridlayout(app.GridLayout3);
            app.ApertureLabel = uilabel(app.ApertureGridLayout);
            app.ApertureLabel.Text = 'Aperture';
            app.GridLayout4 = uigridlayout(app.ApertureGridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ApertureShapeDropDownLabel = uilabel(app.GridLayout5);
            app.ApertureShapeDropDownLabel.Text = 'Aperture Shape';
            app.ApertureShapeDropDown = uidropdown(app.GridLayout5);
            app.ApertureShapeDropDown.Items = {'Circle', 'Rectangle'};
            app.ApertureShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ApertureShapeDropDownValueChanged, true);
            app.ApertureShapeDropDown.Value = 'Circle';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.WidthmmLabel = uilabel(app.GridLayout7);
            app.WidthmmLabel.Visible = 'off';
            app.WidthmmLabel.Text = 'Width (mm)';
            app.HeightmmLabel = uilabel(app.GridLayout7);
            app.HeightmmLabel.Visible = 'off';
            app.HeightmmLabel.Text = 'Height (mm)';
            app.RadiusEditField = uieditfield(app.GridLayout7, 'numeric');
            app.HeightEditField = uieditfield(app.GridLayout7, 'numeric');
            app.HeightEditField.Visible = 'off';
            app.RadiusmmLabel = uilabel(app.GridLayout7);
            app.RadiusmmLabel.Text = 'Radius (mm)';
            app.WidthEditField = uieditfield(app.GridLayout7, 'numeric');
            app.WidthEditField.Visible = 'off';
            app.PLDGridLayout = uigridlayout(app.GridLayout);
            app.PLDFileLabel = uilabel(app.PLDGridLayout);
            app.PLDFileLabel.Text = 'PLD File';
            app.PLDEditField = uieditfield(app.PLDGridLayout, 'text');
            app.PLDEditField.Editable = 'off';
            app.PLDButton = uibutton(app.PLDGridLayout, 'push');
            app.PLDButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushedFcn, true);
            app.PLDButton.Text = 'Select';
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.SnoutPositionEditFieldLabel = uilabel(app.GridLayout9);
            app.SnoutPositionEditFieldLabel.Text = 'Snout Position';
            app.SnoutPositionEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ApertureThicknessbydefault60mmEditFieldLabel = uilabel(app.GridLayout9);
            app.ApertureThicknessbydefault60mmEditFieldLabel.Text = 'Aperture Thickness (by default 60mm)';
            app.ApertureThicknessbydefault60mmEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ApertureThicknessbydefault60mmEditField.Value = 60;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_designer_ShootThrough

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end